classdef MedicalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SaveimageButton  matlab.ui.control.Button
        CropimageButton  matlab.ui.control.Button
        LoadimageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage
        CroppedImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.dcm;*.png;*.jpg;*.jpeg;*.tif','Image Files'});
            if isequal(file, 0)
                return;
            end
        
            fullPath = fullfile(path, file);
        
            try
                if endsWith(file, '.dcm')
                    img = dicomread(fullPath);
                else
                    img = imread(fullPath);
                end
            catch
                uialert(app.UIFigure, 'Could not load image.', 'Load Error');
                return;
            end
        
            app.OriginalImage = img;
        
            % Display in UIAxes
            imshow(img, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Original Image');
        end

        % Button pushed function: CropimageButton
        function CropimageButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Load an image first.', 'Error');
                return;
            end
        
            % User draws cropping rectangle
            rect = drawrectangle(app.UIAxes);
            wait(rect);  % wait until user completes rectangle
        
            % Crop image
            app.CroppedImage = imcrop(app.OriginalImage, rect.Position);
        
            imshow(app.CroppedImage, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Cropped Image');
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if isempty(app.CroppedImage)
                uialert(app.UIFigure, 'No cropped image to save.', 'Error');
                return;
            end
        
            [file, path] = uiputfile({'*.png';'*.jpg';'*.tif'}, 'Save Cropped Image');
            if isequal(file, 0)
                return;
            end
        
            imwrite(app.CroppedImage, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.CropimageButton = uibutton(app.UIFigure, 'push');
            app.CropimageButton.ButtonPushedFcn = createCallbackFcn(app, @CropimageButtonPushed, true);
            app.CropimageButton.Text = 'Crop image';
            app.SaveimageButton = uibutton(app.UIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MedicalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end